classdef ImageLabeler_box_v2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        IMAGELABELERUIFigure    matlab.ui.Figure
        LoaddirectoryButton     matlab.ui.control.Button
        NEXTButton              matlab.ui.control.Button
        PREVIOUSButton          matlab.ui.control.Button
        DELETEButton            matlab.ui.control.Button
        ASSIGNButton_1          matlab.ui.control.Button
        LabelEditFieldLabel     matlab.ui.control.Label
        LabelEditField_1        matlab.ui.control.EditField
        MALEButton              matlab.ui.control.Button
        FEMALEButton            matlab.ui.control.Button
        JUVENILEButton          matlab.ui.control.Button
        ASSIGNButton_2          matlab.ui.control.Button
        LabelEditField_2Label   matlab.ui.control.Label
        LabelEditField_2        matlab.ui.control.EditField
        ASSIGNButton_3          matlab.ui.control.Button
        LabelEditField_3Label   matlab.ui.control.Label
        LabelEditField_3        matlab.ui.control.EditField
        ASSIGNButton_4          matlab.ui.control.Button
        LabelEditField_4Label   matlab.ui.control.Label
        LabelEditField_4        matlab.ui.control.EditField
        ASSIGNButton_5          matlab.ui.control.Button
        LabelEditField_5Label   matlab.ui.control.Label
        LabelEditField_5        matlab.ui.control.EditField
        ASSIGNButton_6          matlab.ui.control.Button
        LabelEditField_6Label   matlab.ui.control.Label
        LabelEditField_6        matlab.ui.control.EditField
        ASSIGNButton_7          matlab.ui.control.Button
        LabelEditField_7Label   matlab.ui.control.Label
        LabelEditField_7        matlab.ui.control.EditField
        ASSIGNButton_8          matlab.ui.control.Button
        LabelEditField_8Label   matlab.ui.control.Label
        LabelEditField_8        matlab.ui.control.EditField
        ASSIGNButton_9          matlab.ui.control.Button
        LabelEditField_9Label   matlab.ui.control.Label
        LabelEditField_9        matlab.ui.control.EditField
        ASSIGNButton_10         matlab.ui.control.Button
        LabelEditField_10Label  matlab.ui.control.Label
        LabelEditField_10       matlab.ui.control.EditField
        AssignedlabelsPanel     matlab.ui.container.Panel
        SpeciesLabel            matlab.ui.control.Label
        SexLabel                matlab.ui.control.Label
        PlumageLabel            matlab.ui.control.Label
        SpeciesLabelTxt         matlab.ui.control.Label
        SexLabelTxt             matlab.ui.control.Label
        PlumageLabelTxt         matlab.ui.control.Label
        xminLabel               matlab.ui.control.Label
        xminLabelTxt            matlab.ui.control.Label
        xmaxLabelTxt            matlab.ui.control.Label
        xmaxLabel               matlab.ui.control.Label
        yminLabelTxt            matlab.ui.control.Label
        yminLabel               matlab.ui.control.Label
        ymaxLabelTxt            matlab.ui.control.Label
        ymaxLabel               matlab.ui.control.Label
        ReadyLampLabel          matlab.ui.control.Label
        ReadyLamp               matlab.ui.control.Lamp
        SAVEButton              matlab.ui.control.Button
        ASSIGNButton_11         matlab.ui.control.Button
        LabelEditField_11Label  matlab.ui.control.Label
        LabelEditField_11       matlab.ui.control.EditField
        ASSIGNButton_12         matlab.ui.control.Button
        LabelEditField_12Label  matlab.ui.control.Label
        LabelEditField_12       matlab.ui.control.EditField
        ASSIGNButton_13         matlab.ui.control.Button
        LabelEditField_13Label  matlab.ui.control.Label
        LabelEditField_13       matlab.ui.control.EditField
        ASSIGNButton_14         matlab.ui.control.Button
        LabelEditField_14Label  matlab.ui.control.Label
        LabelEditField_14       matlab.ui.control.EditField
        ASSIGNButton_15         matlab.ui.control.Button
        LabelEditField_15Label  matlab.ui.control.Label
        LabelEditField_15       matlab.ui.control.EditField
        ASSIGNButton_16         matlab.ui.control.Button
        LabelEditField_16Label  matlab.ui.control.Label
        LabelEditField_16       matlab.ui.control.EditField
        ASSIGNButton_17         matlab.ui.control.Button
        LabelEditField_17Label  matlab.ui.control.Label
        LabelEditField_17       matlab.ui.control.EditField
        ASSIGNButton_18         matlab.ui.control.Button
        LabelEditField_18Label  matlab.ui.control.Label
        LabelEditField_18       matlab.ui.control.EditField
        ASSIGNButton_19         matlab.ui.control.Button
        LabelEditField_19Label  matlab.ui.control.Label
        LabelEditField_19       matlab.ui.control.EditField
        ASSIGNButton_20         matlab.ui.control.Button
        LabelEditField_20Label  matlab.ui.control.Label
        LabelEditField_20       matlab.ui.control.EditField
        ASSIGNButton_21         matlab.ui.control.Button
        LabelEditField_21Label  matlab.ui.control.Label
        LabelEditField_21       matlab.ui.control.EditField
        ASSIGNButton_22         matlab.ui.control.Button
        LabelEditField_22Label  matlab.ui.control.Label
        LabelEditField_22       matlab.ui.control.EditField
        ASSIGNButton_23         matlab.ui.control.Button
        LabelEditField_23Label  matlab.ui.control.Label
        LabelEditField_23       matlab.ui.control.EditField
        ASSIGNButton_24         matlab.ui.control.Button
        LabelEditField_24Label  matlab.ui.control.Label
        LabelEditField_24       matlab.ui.control.EditField
        ASSIGNButton_25         matlab.ui.control.Button
        LabelEditField_25Label  matlab.ui.control.Label
        LabelEditField_25       matlab.ui.control.EditField
        ASSIGNButton_26         matlab.ui.control.Button
        LabelEditField_26Label  matlab.ui.control.Label
        LabelEditField_26       matlab.ui.control.EditField
        ASSIGNButton_27         matlab.ui.control.Button
        LabelEditField_27Label  matlab.ui.control.Label
        LabelEditField_27       matlab.ui.control.EditField
        ASSIGNButton_28         matlab.ui.control.Button
        LabelEditField_28Label  matlab.ui.control.Label
        LabelEditField_28       matlab.ui.control.EditField
        ASSIGNButton_29         matlab.ui.control.Button
        LabelEditField_29Label  matlab.ui.control.Label
        LabelEditField_29       matlab.ui.control.EditField
        ASSIGNButton_30         matlab.ui.control.Button
        LabelEditField_30Label  matlab.ui.control.Label
        LabelEditField_30       matlab.ui.control.EditField
        ASSIGNButton_31         matlab.ui.control.Button
        LabelEditField_31Label  matlab.ui.control.Label
        LabelEditField_31       matlab.ui.control.EditField
        ASSIGNButton_32         matlab.ui.control.Button
        LabelEditField_32Label  matlab.ui.control.Label
        LabelEditField_32       matlab.ui.control.EditField
        ASSIGNButton_33         matlab.ui.control.Button
        LabelEditField_33Label  matlab.ui.control.Label
        LabelEditField_33       matlab.ui.control.EditField
        ASSIGNButton_34         matlab.ui.control.Button
        LabelEditField_34Label  matlab.ui.control.Label
        LabelEditField_34       matlab.ui.control.EditField
        ASSIGNButton_35         matlab.ui.control.Button
        LabelEditField_35Label  matlab.ui.control.Label
        LabelEditField_35       matlab.ui.control.EditField
        ASSIGNButton_36         matlab.ui.control.Button
        LabelEditField_36Label  matlab.ui.control.Label
        LabelEditField_36       matlab.ui.control.EditField
        ASSIGNButton_37         matlab.ui.control.Button
        LabelEditField_37Label  matlab.ui.control.Label
        LabelEditField_37       matlab.ui.control.EditField
        ASSIGNButton_38         matlab.ui.control.Button
        LabelEditField_38Label  matlab.ui.control.Label
        LabelEditField_38       matlab.ui.control.EditField
        ASSIGNButton_39         matlab.ui.control.Button
        LabelEditField_39Label  matlab.ui.control.Label
        LabelEditField_39       matlab.ui.control.EditField
        ASSIGNButton_40         matlab.ui.control.Button
        LabelEditField_40Label  matlab.ui.control.Label
        LabelEditField_40       matlab.ui.control.EditField
        NONBREEDINGButton       matlab.ui.control.Button
        NAButton                matlab.ui.control.Button
        NOTDISCERNABLEButton    matlab.ui.control.Button
        ADDButton               matlab.ui.control.Button
        LasttaggedButton        matlab.ui.control.Button
        ASSIGNButton_60         matlab.ui.control.Button
        LabelEditField_41Label  matlab.ui.control.Label
        LabelEditField_60       matlab.ui.control.EditField
        ASSIGNButton_59         matlab.ui.control.Button
        LabelEditField_42Label  matlab.ui.control.Label
        LabelEditField_59       matlab.ui.control.EditField
        ASSIGNButton_58         matlab.ui.control.Button
        LabelEditField_43Label  matlab.ui.control.Label
        LabelEditField_58       matlab.ui.control.EditField
        ASSIGNButton_57         matlab.ui.control.Button
        LabelEditField_44Label  matlab.ui.control.Label
        LabelEditField_57       matlab.ui.control.EditField
        ASSIGNButton_56         matlab.ui.control.Button
        LabelEditField_45Label  matlab.ui.control.Label
        LabelEditField_56       matlab.ui.control.EditField
        ASSIGNButton_55         matlab.ui.control.Button
        LabelEditField_46Label  matlab.ui.control.Label
        LabelEditField_55       matlab.ui.control.EditField
        ASSIGNButton_54         matlab.ui.control.Button
        LabelEditField_47Label  matlab.ui.control.Label
        LabelEditField_54       matlab.ui.control.EditField
        ASSIGNButton_53         matlab.ui.control.Button
        LabelEditField_48Label  matlab.ui.control.Label
        LabelEditField_53       matlab.ui.control.EditField
        ASSIGNButton_52         matlab.ui.control.Button
        LabelEditField_49Label  matlab.ui.control.Label
        LabelEditField_52       matlab.ui.control.EditField
        ASSIGNButton_51         matlab.ui.control.Button
        LabelEditField_50Label  matlab.ui.control.Label
        LabelEditField_51       matlab.ui.control.EditField
        ASSIGNButton_50x        matlab.ui.control.Button
        LabelEditField_51Label  matlab.ui.control.Label
        LabelEditField_50       matlab.ui.control.EditField
        ASSIGNButton_49         matlab.ui.control.Button
        LabelEditField_52Label  matlab.ui.control.Label
        LabelEditField_49       matlab.ui.control.EditField
        ASSIGNButton_48         matlab.ui.control.Button
        LabelEditField_53Label  matlab.ui.control.Label
        LabelEditField_48       matlab.ui.control.EditField
        ASSIGNButton_47         matlab.ui.control.Button
        LabelEditField_54Label  matlab.ui.control.Label
        LabelEditField_47       matlab.ui.control.EditField
        ASSIGNButton_46         matlab.ui.control.Button
        LabelEditField_55Label  matlab.ui.control.Label
        LabelEditField_46       matlab.ui.control.EditField
        ASSIGNButton_45         matlab.ui.control.Button
        LabelEditField_56Label  matlab.ui.control.Label
        LabelEditField_45       matlab.ui.control.EditField
        ASSIGNButton_44         matlab.ui.control.Button
        LabelEditField_57Label  matlab.ui.control.Label
        LabelEditField_44       matlab.ui.control.EditField
        ASSIGNButton_43         matlab.ui.control.Button
        LabelEditField_58Label  matlab.ui.control.Label
        LabelEditField_43       matlab.ui.control.EditField
        ASSIGNButton_42         matlab.ui.control.Button
        LabelEditField_59Label  matlab.ui.control.Label
        LabelEditField_42       matlab.ui.control.EditField
        ASSIGNButton_41         matlab.ui.control.Button
        LabelEditField_60Label  matlab.ui.control.Label
        LabelEditField_41       matlab.ui.control.EditField
    end


    properties (Access = private)
        imglist % Description
        imglistIdx = 1;
        numCategories = 40; % Description
        coords = struct; % Description
        rect = []; % Description
    end

    methods (Access = public)
    
        function display(app)
            
            app.ReadyLamp.Color = 'r';
            drawnow
            
            img = imread([app.imglist(app.imglistIdx).folder '\' app.imglist(app.imglistIdx).name]);
            
%             cla(app.UIAxes)
%             axis(app.UIAxes, 'auto')
            figure(100)
            cla
            image(gca, img, 'ButtonDownFcn',@app.img_buttondown)
            
            title(gca, ['Img ' num2str(app.imglistIdx) ' (of ' num2str(length(app.imglist)) '): ' app.imglist(app.imglistIdx).name])

            app.ReadyLamp.Color = 'g';
            drawnow

            
        end
        
        
        function img_buttondown(app, src, hit)
            
            [xlist, ylist]=ginput(2);
            app.coords.x_min = round(min(xlist));
            app.coords.x_max = round(max(xlist));
            app.coords.y_min = round(min(ylist));
            app.coords.y_max = round(max(ylist));
            
            x = app.coords.x_min;
            y = app.coords.y_min;
            w= app.coords.x_max - app.coords.x_min;
            h= app.coords.y_max - app.coords.y_min;
            
            pos = [x, y, w, h];
            app.rect = rectangle('Position',pos, 'EdgeColor', 'r', 'linewidth',2);
            
            app.imglist(app.imglistIdx).x_min = app.coords.x_min; 
            app.imglist(app.imglistIdx).x_max = app.coords.x_max; 
            app.imglist(app.imglistIdx).y_min = app.coords.y_min; 
            app.imglist(app.imglistIdx).y_max = app.coords.y_max; 
             app.updateLabelInfo()
        end
        
        function applyLabel(app, event)
            disp(' ')
        end
        
    end

    methods (Access = private)
    
        function updateLabelInfo(app)
            
            if isempty(app.imglist(app.imglistIdx).Label)
                app.SpeciesLabelTxt.Text = 'None';
            else
                app.SpeciesLabelTxt.Text = app.imglist(app.imglistIdx).Label;
            end
            
            if isempty(app.imglist(app.imglistIdx).Sex)
                app.SexLabelTxt.Text  = 'None';
            else
                app.SexLabelTxt.Text = app.imglist(app.imglistIdx).Sex;
            end
            
            if isempty(app.imglist(app.imglistIdx).Plumage)
                app.PlumageLabelTxt.Text = 'None';
            else
                app.PlumageLabelTxt.Text = app.imglist(app.imglistIdx).Plumage;
            end 
            
            if isempty(app.imglist(app.imglistIdx).x_min)
                app.xminLabelTxt.Text = '-';
            else
                app.xminLabelTxt.Text = num2str(app.imglist(app.imglistIdx).x_min);
            end 
            
            if isempty(app.imglist(app.imglistIdx).x_max)
                app.xmaxLabelTxt.Text = '-';
            else
                app.xmaxLabelTxt.Text = num2str(app.imglist(app.imglistIdx).x_max);
            end 
            
            if isempty(app.imglist(app.imglistIdx).y_min)
                app.yminLabelTxt.Text = '-';
            else
                app.yminLabelTxt.Text = num2str(app.imglist(app.imglistIdx).y_min);
            end 
            
            if isempty(app.imglist(app.imglistIdx).y_max)
                app.ymaxLabelTxt.Text = '-';
            else
                app.ymaxLabelTxt.Text = num2str(app.imglist(app.imglistIdx).y_max);
            end 
            
            x_min = app.imglist(app.imglistIdx).x_min;
            x_max = app.imglist(app.imglistIdx).x_max;
            y_min = app.imglist(app.imglistIdx).y_min;
            y_max = app.imglist(app.imglistIdx).y_max;
            
            if ~isempty(x_min) & ~isempty(x_max) & ~isempty(y_min) & ~isempty(y_min) 
                figure(100)
                x = x_min;
                y = y_min;
                w= x_max - x_min;
                h= y_max - y_min;
            
                pos = [x, y, w, h];
                app.rect = rectangle('Position',pos, 'EdgeColor', 'g', 'linewidth',1);     
            end
            
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)


        end

        % Button pushed function: ADDButton
        function ADDButtonPushed(app, event)
                        
            
            app.imglist(app.imglistIdx)
             
            
            try
            %             a = app.imglist;
            a1 = app.imglist(1 : app.imglistIdx);
            a2 = app.imglist(app.imglistIdx+1:end);
            
            %             fs = fieldnames(a1);
            
            a1(end+1).name = a1(end).name;
            a1(end).folder = a1(end-1).folder;
            a1(end).date = date;
            a1(end).bytes = a1(end-1).bytes;
            a1(end).isdir = a1(end-1).isdir;
            a1(end).datenum = now;
            
            len =  length(a1);
            
            for i = 1 : length(a2)
    
                a1(i+len) = a2(i);
    
    
            end
            app.imglist = a1;
            app.imglistIdx = app.imglistIdx + 1;
            app.updateLabelInfo()
            catch ERROR
                beep
                errordlg('Not sussess!')
                
            end


        end

        % Button pushed function: ASSIGNButton_1, ASSIGNButton_10, 
        % ASSIGNButton_11, ASSIGNButton_12, ASSIGNButton_13, 
        % ASSIGNButton_14, ASSIGNButton_15, ASSIGNButton_16, 
        % ASSIGNButton_17, ASSIGNButton_18, ASSIGNButton_19, 
        % ASSIGNButton_2, ASSIGNButton_20, ASSIGNButton_21, 
        % ASSIGNButton_22, ASSIGNButton_23, ASSIGNButton_24, 
        % ASSIGNButton_25, ASSIGNButton_26, ASSIGNButton_27, 
        % ASSIGNButton_28, ASSIGNButton_29, ASSIGNButton_3, 
        % ASSIGNButton_30, ASSIGNButton_31, ASSIGNButton_32, 
        % ASSIGNButton_33, ASSIGNButton_34, ASSIGNButton_35, 
        % ASSIGNButton_36, ASSIGNButton_37, ASSIGNButton_38, 
        % ASSIGNButton_39, ASSIGNButton_4, ASSIGNButton_40, 
        % ASSIGNButton_41, ASSIGNButton_42, ASSIGNButton_43, 
        % ASSIGNButton_44, ASSIGNButton_45, ASSIGNButton_46, 
        % ASSIGNButton_47, ASSIGNButton_48, ASSIGNButton_49, 
        % ASSIGNButton_5, ASSIGNButton_50x, ASSIGNButton_51, 
        % ASSIGNButton_52, ASSIGNButton_53, ASSIGNButton_54, 
        % ASSIGNButton_55, ASSIGNButton_56, ASSIGNButton_57, 
        % ASSIGNButton_58, ASSIGNButton_59, ASSIGNButton_6, 
        % ASSIGNButton_60, ASSIGNButton_7, ASSIGNButton_8, ASSIGNButton_9
        function ASSIGNButton_1Pushed(app, event)
            
            app.imglist(app.imglistIdx).Label = upper(app.(['LabelEditField_' event.Source.Text]).Value);
            app.updateLabelInfo()
            
        end

        % Button pushed function: DELETEButton
        function DELETEButtonPushed(app, event)
             app.imglist(app.imglistIdx).Label = 'DELETE';
             app.updateLabelInfo()
        end

        % Button pushed function: FEMALEButton
        function FEMALEButtonPushed(app, event)
            app.imglist(app.imglistIdx).Sex = 'FEMALE';
            app.updateLabelInfo()
        end

        % Button pushed function: JUVENILEButton
        function JUVENILEButtonPushed(app, event)
            app.imglist(app.imglistIdx).Plumage = 'JUVENILE';
            app.updateLabelInfo()
        end

        % Button pushed function: LasttaggedButton
        function LasttaggedButtonPushed(app, event)
            
            numEmpty = 0;
            next = 1;
            while numEmpty <= 10
                
               if isempty(app.imglist(next).Label)
                   numEmpty = numEmpty + 1;
                   
               else
                   
                   numEmpty = 0;
               end
               
               
               next = next + 1;
               
            end
            app.imglistIdx = next - 10 - 1;
            
            app.display()
            
            app.updateLabelInfo()
        end

        % Button pushed function: LoaddirectoryButton
        function LoaddirectoryButtonPushed(app, event)
            filepath = uigetdir;
            cd(filepath)
            
            m = dir('labels.mat');
            if isempty(m)
                app.imglist = dir('*.jpg');
                app.imglist(1).Label = [];
                app.imglist(1).Sex = [];
                app.imglist(1).Plumage = [];
            else
               loadedList = load(m.name);
               app.imglist = loadedList.data;  
               app.imglistIdx = loadedList.idx;
               
            for i = 1 : length(loadedList.cats)
                
                app.(['LabelEditField_' num2str(i)]).Value = loadedList.cats{i};
                
            end               
               
               
            end
            
            app.display()
            app.updateLabelInfo()
            
        end

        % Button pushed function: MALEButton
        function MALEButtonPushed(app, event)
            app.imglist(app.imglistIdx).Sex = 'MALE';
            app.updateLabelInfo()
        end

        % Button pushed function: NAButton
        function NAButtonPushed(app, event)
            app.imglist(app.imglistIdx).Plumage = [];
            app.updateLabelInfo()  
        end

        % Button pushed function: NEXTButton
        function NEXTButtonPushed(app, event)
            app.imglistIdx = min(app.imglistIdx + 1, length(app.imglist));
            
            app.display()
            
            app.updateLabelInfo()
            
        end

        % Button pushed function: NONBREEDINGButton
        function NONBREEDINGButtonPushed(app, event)
            app.imglist(app.imglistIdx).Plumage = 'NON-BREEDING';
            app.updateLabelInfo()            
        end

        % Button pushed function: NOTDISCERNABLEButton
        function NOTDISCERNABLEButtonPushed(app, event)
            app.imglist(app.imglistIdx).Sex = 'NOT DISCERNABLE';
            app.updateLabelInfo()
        end

        % Button pushed function: PREVIOUSButton
        function PREVIOUSButtonPushed(app, event)
           app.imglistIdx = max(app.imglistIdx - 1, 1);
            
            app.display()
            
            app.updateLabelInfo()
            
        end

        % Button pushed function: SAVEButton
        function SAVEButtonPushed(app, event)
            data = app.imglist;
            idx = app.imglistIdx;
            
            cats = cell(app.numCategories, 1);
            for i = 1 : app.numCategories
                
                cats{i} = app.(['LabelEditField_' num2str(i)]).Value;
                
            end
            
            
            save('labels.mat', 'data','idx', 'cats')
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.IMAGELABELERUIFigure = uifigure;
            app.IMAGELABELERUIFigure.Name = 'IMAGE LABELER';
            app.LoaddirectoryButton = uibutton(app.IMAGELABELERUIFigure, 'push');
            app.LoaddirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @LoaddirectoryButtonPushed, true);
            app.LoaddirectoryButton.Text = 'Load directory';
            app.NEXTButton = uibutton(app.IMAGELABELERUIFigure, 'push');
            app.NEXTButton.ButtonPushedFcn = createCallbackFcn(app, @NEXTButtonPushed, true);
            app.NEXTButton.Text = 'NEXT';
            app.PREVIOUSButton = uibutton(app.IMAGELABELERUIFigure, 'push');
            app.PREVIOUSButton.ButtonPushedFcn = createCallbackFcn(app, @PREVIOUSButtonPushed, true);
            app.PREVIOUSButton.Text = 'PREVIOUS';
            app.DELETEButton = uibutton(app.IMAGELABELERUIFigure, 'push');
            app.DELETEButton.ButtonPushedFcn = createCallbackFcn(app, @DELETEButtonPushed, true);
            app.DELETEButton.Text = 'DELETE';
            app.ASSIGNButton_1 = uibutton(app.IMAGELABELERUIFigure, 'push');
            app.ASSIGNButton_1.ButtonPushedFcn = createCallbackFcn(app, @ASSIGNButton_1Pushed, true);
            app.ASSIGNButton_1.Text = '1';
            app.LabelEditFieldLabel = uilabel(app.IMAGELABELERUIFigure);
            app.LabelEditFieldLabel.Text = 'Label:';
            app.LabelEditField_1 = uieditfield(app.IMAGELABELERUIFigure, 'text');
            app.MALEButton = uibutton(app.IMAGELABELERUIFigure, 'push');
            app.MALEButton.ButtonPushedFcn = createCallbackFcn(app, @MALEButtonPushed, true);
            app.MALEButton.Text = 'MALE';
            app.FEMALEButton = uibutton(app.IMAGELABELERUIFigure, 'push');
            app.FEMALEButton.ButtonPushedFcn = createCallbackFcn(app, @FEMALEButtonPushed, true);
            app.FEMALEButton.Text = 'FEMALE';
            app.JUVENILEButton = uibutton(app.IMAGELABELERUIFigure, 'push');
            app.JUVENILEButton.ButtonPushedFcn = createCallbackFcn(app, @JUVENILEButtonPushed, true);
            app.JUVENILEButton.Text = 'JUVENILE';
            app.ASSIGNButton_2 = uibutton(app.IMAGELABELERUIFigure, 'push');
            app.ASSIGNButton_2.ButtonPushedFcn = createCallbackFcn(app, @ASSIGNButton_1Pushed, true);
            app.ASSIGNButton_2.Text = '2';
            app.LabelEditField_2Label = uilabel(app.IMAGELABELERUIFigure);
            app.LabelEditField_2Label.Text = 'Label:';
            app.LabelEditField_2 = uieditfield(app.IMAGELABELERUIFigure, 'text');
            app.ASSIGNButton_3 = uibutton(app.IMAGELABELERUIFigure, 'push');
            app.ASSIGNButton_3.ButtonPushedFcn = createCallbackFcn(app, @ASSIGNButton_1Pushed, true);
            app.ASSIGNButton_3.Text = '3';
            app.LabelEditField_3Label = uilabel(app.IMAGELABELERUIFigure);
            app.LabelEditField_3Label.Text = 'Label:';
            app.LabelEditField_3 = uieditfield(app.IMAGELABELERUIFigure, 'text');
            app.ASSIGNButton_4 = uibutton(app.IMAGELABELERUIFigure, 'push');
            app.ASSIGNButton_4.ButtonPushedFcn = createCallbackFcn(app, @ASSIGNButton_1Pushed, true);
            app.ASSIGNButton_4.Text = '4';
            app.LabelEditField_4Label = uilabel(app.IMAGELABELERUIFigure);
            app.LabelEditField_4Label.Text = 'Label:';
            app.LabelEditField_4 = uieditfield(app.IMAGELABELERUIFigure, 'text');
            app.ASSIGNButton_5 = uibutton(app.IMAGELABELERUIFigure, 'push');
            app.ASSIGNButton_5.ButtonPushedFcn = createCallbackFcn(app, @ASSIGNButton_1Pushed, true);
            app.ASSIGNButton_5.Text = '5';
            app.LabelEditField_5Label = uilabel(app.IMAGELABELERUIFigure);
            app.LabelEditField_5Label.Text = 'Label:';
            app.LabelEditField_5 = uieditfield(app.IMAGELABELERUIFigure, 'text');
            app.ASSIGNButton_6 = uibutton(app.IMAGELABELERUIFigure, 'push');
            app.ASSIGNButton_6.ButtonPushedFcn = createCallbackFcn(app, @ASSIGNButton_1Pushed, true);
            app.ASSIGNButton_6.Text = '6';
            app.LabelEditField_6Label = uilabel(app.IMAGELABELERUIFigure);
            app.LabelEditField_6Label.Text = 'Label:';
            app.LabelEditField_6 = uieditfield(app.IMAGELABELERUIFigure, 'text');
            app.ASSIGNButton_7 = uibutton(app.IMAGELABELERUIFigure, 'push');
            app.ASSIGNButton_7.ButtonPushedFcn = createCallbackFcn(app, @ASSIGNButton_1Pushed, true);
            app.ASSIGNButton_7.Text = '7';
            app.LabelEditField_7Label = uilabel(app.IMAGELABELERUIFigure);
            app.LabelEditField_7Label.Text = 'Label:';
            app.LabelEditField_7 = uieditfield(app.IMAGELABELERUIFigure, 'text');
            app.ASSIGNButton_8 = uibutton(app.IMAGELABELERUIFigure, 'push');
            app.ASSIGNButton_8.ButtonPushedFcn = createCallbackFcn(app, @ASSIGNButton_1Pushed, true);
            app.ASSIGNButton_8.Text = '8';
            app.LabelEditField_8Label = uilabel(app.IMAGELABELERUIFigure);
            app.LabelEditField_8Label.Text = 'Label:';
            app.LabelEditField_8 = uieditfield(app.IMAGELABELERUIFigure, 'text');
            app.ASSIGNButton_9 = uibutton(app.IMAGELABELERUIFigure, 'push');
            app.ASSIGNButton_9.ButtonPushedFcn = createCallbackFcn(app, @ASSIGNButton_1Pushed, true);
            app.ASSIGNButton_9.Text = '9';
            app.LabelEditField_9Label = uilabel(app.IMAGELABELERUIFigure);
            app.LabelEditField_9Label.Text = 'Label:';
            app.LabelEditField_9 = uieditfield(app.IMAGELABELERUIFigure, 'text');
            app.ASSIGNButton_10 = uibutton(app.IMAGELABELERUIFigure, 'push');
            app.ASSIGNButton_10.ButtonPushedFcn = createCallbackFcn(app, @ASSIGNButton_1Pushed, true);
            app.ASSIGNButton_10.Text = '10';
            app.LabelEditField_10Label = uilabel(app.IMAGELABELERUIFigure);
            app.LabelEditField_10Label.Text = 'Label:';
            app.LabelEditField_10 = uieditfield(app.IMAGELABELERUIFigure, 'text');
            app.AssignedlabelsPanel = uipanel(app.IMAGELABELERUIFigure);
            app.AssignedlabelsPanel.Title = 'Assigned labels';
            app.SpeciesLabel = uilabel(app.AssignedlabelsPanel);
            app.SpeciesLabel.Text = 'Species:';
            app.SexLabel = uilabel(app.AssignedlabelsPanel);
            app.SexLabel.Text = 'Sex:';
            app.PlumageLabel = uilabel(app.AssignedlabelsPanel);
            app.PlumageLabel.Text = 'Plumage:';
            app.SpeciesLabelTxt = uilabel(app.AssignedlabelsPanel);
            app.SpeciesLabelTxt.Text = 'None';
            app.SexLabelTxt = uilabel(app.AssignedlabelsPanel);
            app.SexLabelTxt.Text = 'None';
            app.PlumageLabelTxt = uilabel(app.AssignedlabelsPanel);
            app.PlumageLabelTxt.Text = 'None';
            app.xminLabel = uilabel(app.AssignedlabelsPanel);
            app.xminLabel.Text = 'x min';
            app.xminLabelTxt = uilabel(app.AssignedlabelsPanel);
            app.xminLabelTxt.Text = 'None';
            app.xmaxLabelTxt = uilabel(app.AssignedlabelsPanel);
            app.xmaxLabelTxt.Text = 'None';
            app.xmaxLabel = uilabel(app.AssignedlabelsPanel);
            app.xmaxLabel.Text = 'x max';
            app.yminLabelTxt = uilabel(app.AssignedlabelsPanel);
            app.yminLabelTxt.Text = 'None';
            app.yminLabel = uilabel(app.AssignedlabelsPanel);
            app.yminLabel.Text = 'y min';
            app.ymaxLabelTxt = uilabel(app.AssignedlabelsPanel);
            app.ymaxLabelTxt.Text = 'None';
            app.ymaxLabel = uilabel(app.AssignedlabelsPanel);
            app.ymaxLabel.Text = 'y max';
            app.ReadyLampLabel = uilabel(app.IMAGELABELERUIFigure);
            app.ReadyLampLabel.Text = 'Ready';
            app.ReadyLamp = uilamp(app.IMAGELABELERUIFigure);
            app.SAVEButton = uibutton(app.IMAGELABELERUIFigure, 'push');
            app.SAVEButton.ButtonPushedFcn = createCallbackFcn(app, @SAVEButtonPushed, true);
            app.SAVEButton.Text = 'SAVE';
            app.ASSIGNButton_11 = uibutton(app.IMAGELABELERUIFigure, 'push');
            app.ASSIGNButton_11.ButtonPushedFcn = createCallbackFcn(app, @ASSIGNButton_1Pushed, true);
            app.ASSIGNButton_11.Text = '11';
            app.LabelEditField_11Label = uilabel(app.IMAGELABELERUIFigure);
            app.LabelEditField_11Label.Text = 'Label:';
            app.LabelEditField_11 = uieditfield(app.IMAGELABELERUIFigure, 'text');
            app.ASSIGNButton_12 = uibutton(app.IMAGELABELERUIFigure, 'push');
            app.ASSIGNButton_12.ButtonPushedFcn = createCallbackFcn(app, @ASSIGNButton_1Pushed, true);
            app.ASSIGNButton_12.Text = '12';
            app.LabelEditField_12Label = uilabel(app.IMAGELABELERUIFigure);
            app.LabelEditField_12Label.Text = 'Label:';
            app.LabelEditField_12 = uieditfield(app.IMAGELABELERUIFigure, 'text');
            app.ASSIGNButton_13 = uibutton(app.IMAGELABELERUIFigure, 'push');
            app.ASSIGNButton_13.ButtonPushedFcn = createCallbackFcn(app, @ASSIGNButton_1Pushed, true);
            app.ASSIGNButton_13.Text = '13';
            app.LabelEditField_13Label = uilabel(app.IMAGELABELERUIFigure);
            app.LabelEditField_13Label.Text = 'Label:';
            app.LabelEditField_13 = uieditfield(app.IMAGELABELERUIFigure, 'text');
            app.ASSIGNButton_14 = uibutton(app.IMAGELABELERUIFigure, 'push');
            app.ASSIGNButton_14.ButtonPushedFcn = createCallbackFcn(app, @ASSIGNButton_1Pushed, true);
            app.ASSIGNButton_14.Text = '14';
            app.LabelEditField_14Label = uilabel(app.IMAGELABELERUIFigure);
            app.LabelEditField_14Label.Text = 'Label:';
            app.LabelEditField_14 = uieditfield(app.IMAGELABELERUIFigure, 'text');
            app.ASSIGNButton_15 = uibutton(app.IMAGELABELERUIFigure, 'push');
            app.ASSIGNButton_15.ButtonPushedFcn = createCallbackFcn(app, @ASSIGNButton_1Pushed, true);
            app.ASSIGNButton_15.Text = '15';
            app.LabelEditField_15Label = uilabel(app.IMAGELABELERUIFigure);
            app.LabelEditField_15Label.Text = 'Label:';
            app.LabelEditField_15 = uieditfield(app.IMAGELABELERUIFigure, 'text');
            app.ASSIGNButton_16 = uibutton(app.IMAGELABELERUIFigure, 'push');
            app.ASSIGNButton_16.ButtonPushedFcn = createCallbackFcn(app, @ASSIGNButton_1Pushed, true);
            app.ASSIGNButton_16.Text = '16';
            app.LabelEditField_16Label = uilabel(app.IMAGELABELERUIFigure);
            app.LabelEditField_16Label.Text = 'Label:';
            app.LabelEditField_16 = uieditfield(app.IMAGELABELERUIFigure, 'text');
            app.ASSIGNButton_17 = uibutton(app.IMAGELABELERUIFigure, 'push');
            app.ASSIGNButton_17.ButtonPushedFcn = createCallbackFcn(app, @ASSIGNButton_1Pushed, true);
            app.ASSIGNButton_17.Text = '17';
            app.LabelEditField_17Label = uilabel(app.IMAGELABELERUIFigure);
            app.LabelEditField_17Label.Text = 'Label:';
            app.LabelEditField_17 = uieditfield(app.IMAGELABELERUIFigure, 'text');
            app.ASSIGNButton_18 = uibutton(app.IMAGELABELERUIFigure, 'push');
            app.ASSIGNButton_18.ButtonPushedFcn = createCallbackFcn(app, @ASSIGNButton_1Pushed, true);
            app.ASSIGNButton_18.Text = '18';
            app.LabelEditField_18Label = uilabel(app.IMAGELABELERUIFigure);
            app.LabelEditField_18Label.Text = 'Label:';
            app.LabelEditField_18 = uieditfield(app.IMAGELABELERUIFigure, 'text');
            app.ASSIGNButton_19 = uibutton(app.IMAGELABELERUIFigure, 'push');
            app.ASSIGNButton_19.ButtonPushedFcn = createCallbackFcn(app, @ASSIGNButton_1Pushed, true);
            app.ASSIGNButton_19.Text = '19';
            app.LabelEditField_19Label = uilabel(app.IMAGELABELERUIFigure);
            app.LabelEditField_19Label.Text = 'Label:';
            app.LabelEditField_19 = uieditfield(app.IMAGELABELERUIFigure, 'text');
            app.ASSIGNButton_20 = uibutton(app.IMAGELABELERUIFigure, 'push');
            app.ASSIGNButton_20.ButtonPushedFcn = createCallbackFcn(app, @ASSIGNButton_1Pushed, true);
            app.ASSIGNButton_20.Text = '20';
            app.LabelEditField_20Label = uilabel(app.IMAGELABELERUIFigure);
            app.LabelEditField_20Label.Text = 'Label:';
            app.LabelEditField_20 = uieditfield(app.IMAGELABELERUIFigure, 'text');
            app.ASSIGNButton_21 = uibutton(app.IMAGELABELERUIFigure, 'push');
            app.ASSIGNButton_21.ButtonPushedFcn = createCallbackFcn(app, @ASSIGNButton_1Pushed, true);
            app.ASSIGNButton_21.Text = '21';
            app.LabelEditField_21Label = uilabel(app.IMAGELABELERUIFigure);
            app.LabelEditField_21Label.Text = 'Label:';
            app.LabelEditField_21 = uieditfield(app.IMAGELABELERUIFigure, 'text');
            app.ASSIGNButton_22 = uibutton(app.IMAGELABELERUIFigure, 'push');
            app.ASSIGNButton_22.ButtonPushedFcn = createCallbackFcn(app, @ASSIGNButton_1Pushed, true);
            app.ASSIGNButton_22.Text = '22';
            app.LabelEditField_22Label = uilabel(app.IMAGELABELERUIFigure);
            app.LabelEditField_22Label.Text = 'Label:';
            app.LabelEditField_22 = uieditfield(app.IMAGELABELERUIFigure, 'text');
            app.ASSIGNButton_23 = uibutton(app.IMAGELABELERUIFigure, 'push');
            app.ASSIGNButton_23.ButtonPushedFcn = createCallbackFcn(app, @ASSIGNButton_1Pushed, true);
            app.ASSIGNButton_23.Text = '23';
            app.LabelEditField_23Label = uilabel(app.IMAGELABELERUIFigure);
            app.LabelEditField_23Label.Text = 'Label:';
            app.LabelEditField_23 = uieditfield(app.IMAGELABELERUIFigure, 'text');
            app.ASSIGNButton_24 = uibutton(app.IMAGELABELERUIFigure, 'push');
            app.ASSIGNButton_24.ButtonPushedFcn = createCallbackFcn(app, @ASSIGNButton_1Pushed, true);
            app.ASSIGNButton_24.Text = '24';
            app.LabelEditField_24Label = uilabel(app.IMAGELABELERUIFigure);
            app.LabelEditField_24Label.Text = 'Label:';
            app.LabelEditField_24 = uieditfield(app.IMAGELABELERUIFigure, 'text');
            app.ASSIGNButton_25 = uibutton(app.IMAGELABELERUIFigure, 'push');
            app.ASSIGNButton_25.ButtonPushedFcn = createCallbackFcn(app, @ASSIGNButton_1Pushed, true);
            app.ASSIGNButton_25.Text = '25';
            app.LabelEditField_25Label = uilabel(app.IMAGELABELERUIFigure);
            app.LabelEditField_25Label.Text = 'Label:';
            app.LabelEditField_25 = uieditfield(app.IMAGELABELERUIFigure, 'text');
            app.ASSIGNButton_26 = uibutton(app.IMAGELABELERUIFigure, 'push');
            app.ASSIGNButton_26.ButtonPushedFcn = createCallbackFcn(app, @ASSIGNButton_1Pushed, true);
            app.ASSIGNButton_26.Text = '26';
            app.LabelEditField_26Label = uilabel(app.IMAGELABELERUIFigure);
            app.LabelEditField_26Label.Text = 'Label:';
            app.LabelEditField_26 = uieditfield(app.IMAGELABELERUIFigure, 'text');
            app.ASSIGNButton_27 = uibutton(app.IMAGELABELERUIFigure, 'push');
            app.ASSIGNButton_27.ButtonPushedFcn = createCallbackFcn(app, @ASSIGNButton_1Pushed, true);
            app.ASSIGNButton_27.Text = '27';
            app.LabelEditField_27Label = uilabel(app.IMAGELABELERUIFigure);
            app.LabelEditField_27Label.Text = 'Label:';
            app.LabelEditField_27 = uieditfield(app.IMAGELABELERUIFigure, 'text');
            app.ASSIGNButton_28 = uibutton(app.IMAGELABELERUIFigure, 'push');
            app.ASSIGNButton_28.ButtonPushedFcn = createCallbackFcn(app, @ASSIGNButton_1Pushed, true);
            app.ASSIGNButton_28.Text = '28';
            app.LabelEditField_28Label = uilabel(app.IMAGELABELERUIFigure);
            app.LabelEditField_28Label.Text = 'Label:';
            app.LabelEditField_28 = uieditfield(app.IMAGELABELERUIFigure, 'text');
            app.ASSIGNButton_29 = uibutton(app.IMAGELABELERUIFigure, 'push');
            app.ASSIGNButton_29.ButtonPushedFcn = createCallbackFcn(app, @ASSIGNButton_1Pushed, true);
            app.ASSIGNButton_29.Text = '29';
            app.LabelEditField_29Label = uilabel(app.IMAGELABELERUIFigure);
            app.LabelEditField_29Label.Text = 'Label:';
            app.LabelEditField_29 = uieditfield(app.IMAGELABELERUIFigure, 'text');
            app.ASSIGNButton_30 = uibutton(app.IMAGELABELERUIFigure, 'push');
            app.ASSIGNButton_30.ButtonPushedFcn = createCallbackFcn(app, @ASSIGNButton_1Pushed, true);
            app.ASSIGNButton_30.Text = '30';
            app.LabelEditField_30Label = uilabel(app.IMAGELABELERUIFigure);
            app.LabelEditField_30Label.Text = 'Label:';
            app.LabelEditField_30 = uieditfield(app.IMAGELABELERUIFigure, 'text');
            app.ASSIGNButton_31 = uibutton(app.IMAGELABELERUIFigure, 'push');
            app.ASSIGNButton_31.ButtonPushedFcn = createCallbackFcn(app, @ASSIGNButton_1Pushed, true);
            app.ASSIGNButton_31.Text = '31';
            app.LabelEditField_31Label = uilabel(app.IMAGELABELERUIFigure);
            app.LabelEditField_31Label.Text = 'Label:';
            app.LabelEditField_31 = uieditfield(app.IMAGELABELERUIFigure, 'text');
            app.ASSIGNButton_32 = uibutton(app.IMAGELABELERUIFigure, 'push');
            app.ASSIGNButton_32.ButtonPushedFcn = createCallbackFcn(app, @ASSIGNButton_1Pushed, true);
            app.ASSIGNButton_32.Text = '32';
            app.LabelEditField_32Label = uilabel(app.IMAGELABELERUIFigure);
            app.LabelEditField_32Label.Text = 'Label:';
            app.LabelEditField_32 = uieditfield(app.IMAGELABELERUIFigure, 'text');
            app.ASSIGNButton_33 = uibutton(app.IMAGELABELERUIFigure, 'push');
            app.ASSIGNButton_33.ButtonPushedFcn = createCallbackFcn(app, @ASSIGNButton_1Pushed, true);
            app.ASSIGNButton_33.Text = '33';
            app.LabelEditField_33Label = uilabel(app.IMAGELABELERUIFigure);
            app.LabelEditField_33Label.Text = 'Label:';
            app.LabelEditField_33 = uieditfield(app.IMAGELABELERUIFigure, 'text');
            app.ASSIGNButton_34 = uibutton(app.IMAGELABELERUIFigure, 'push');
            app.ASSIGNButton_34.ButtonPushedFcn = createCallbackFcn(app, @ASSIGNButton_1Pushed, true);
            app.ASSIGNButton_34.Text = '34';
            app.LabelEditField_34Label = uilabel(app.IMAGELABELERUIFigure);
            app.LabelEditField_34Label.Text = 'Label:';
            app.LabelEditField_34 = uieditfield(app.IMAGELABELERUIFigure, 'text');
            app.ASSIGNButton_35 = uibutton(app.IMAGELABELERUIFigure, 'push');
            app.ASSIGNButton_35.ButtonPushedFcn = createCallbackFcn(app, @ASSIGNButton_1Pushed, true);
            app.ASSIGNButton_35.Text = '35';
            app.LabelEditField_35Label = uilabel(app.IMAGELABELERUIFigure);
            app.LabelEditField_35Label.Text = 'Label:';
            app.LabelEditField_35 = uieditfield(app.IMAGELABELERUIFigure, 'text');
            app.ASSIGNButton_36 = uibutton(app.IMAGELABELERUIFigure, 'push');
            app.ASSIGNButton_36.ButtonPushedFcn = createCallbackFcn(app, @ASSIGNButton_1Pushed, true);
            app.ASSIGNButton_36.Text = '36';
            app.LabelEditField_36Label = uilabel(app.IMAGELABELERUIFigure);
            app.LabelEditField_36Label.Text = 'Label:';
            app.LabelEditField_36 = uieditfield(app.IMAGELABELERUIFigure, 'text');
            app.ASSIGNButton_37 = uibutton(app.IMAGELABELERUIFigure, 'push');
            app.ASSIGNButton_37.ButtonPushedFcn = createCallbackFcn(app, @ASSIGNButton_1Pushed, true);
            app.ASSIGNButton_37.Text = '37';
            app.LabelEditField_37Label = uilabel(app.IMAGELABELERUIFigure);
            app.LabelEditField_37Label.Text = 'Label:';
            app.LabelEditField_37 = uieditfield(app.IMAGELABELERUIFigure, 'text');
            app.ASSIGNButton_38 = uibutton(app.IMAGELABELERUIFigure, 'push');
            app.ASSIGNButton_38.ButtonPushedFcn = createCallbackFcn(app, @ASSIGNButton_1Pushed, true);
            app.ASSIGNButton_38.Text = '38';
            app.LabelEditField_38Label = uilabel(app.IMAGELABELERUIFigure);
            app.LabelEditField_38Label.Text = 'Label:';
            app.LabelEditField_38 = uieditfield(app.IMAGELABELERUIFigure, 'text');
            app.ASSIGNButton_39 = uibutton(app.IMAGELABELERUIFigure, 'push');
            app.ASSIGNButton_39.ButtonPushedFcn = createCallbackFcn(app, @ASSIGNButton_1Pushed, true);
            app.ASSIGNButton_39.Text = '39';
            app.LabelEditField_39Label = uilabel(app.IMAGELABELERUIFigure);
            app.LabelEditField_39Label.Text = 'Label:';
            app.LabelEditField_39 = uieditfield(app.IMAGELABELERUIFigure, 'text');
            app.ASSIGNButton_40 = uibutton(app.IMAGELABELERUIFigure, 'push');
            app.ASSIGNButton_40.ButtonPushedFcn = createCallbackFcn(app, @ASSIGNButton_1Pushed, true);
            app.ASSIGNButton_40.Text = '40';
            app.LabelEditField_40Label = uilabel(app.IMAGELABELERUIFigure);
            app.LabelEditField_40Label.Text = 'Label:';
            app.LabelEditField_40 = uieditfield(app.IMAGELABELERUIFigure, 'text');
            app.NONBREEDINGButton = uibutton(app.IMAGELABELERUIFigure, 'push');
            app.NONBREEDINGButton.ButtonPushedFcn = createCallbackFcn(app, @NONBREEDINGButtonPushed, true);
            app.NONBREEDINGButton.Text = 'NON-BREEDING';
            app.NAButton = uibutton(app.IMAGELABELERUIFigure, 'push');
            app.NAButton.ButtonPushedFcn = createCallbackFcn(app, @NAButtonPushed, true);
            app.NAButton.Text = 'NA';
            app.NOTDISCERNABLEButton = uibutton(app.IMAGELABELERUIFigure, 'push');
            app.NOTDISCERNABLEButton.ButtonPushedFcn = createCallbackFcn(app, @NOTDISCERNABLEButtonPushed, true);
            app.NOTDISCERNABLEButton.Text = 'NOT DISCERNABLE';
            app.ADDButton = uibutton(app.IMAGELABELERUIFigure, 'push');
            app.ADDButton.ButtonPushedFcn = createCallbackFcn(app, @ADDButtonPushed, true);
            app.ADDButton.Text = 'ADD';
            app.LasttaggedButton = uibutton(app.IMAGELABELERUIFigure, 'push');
            app.LasttaggedButton.ButtonPushedFcn = createCallbackFcn(app, @LasttaggedButtonPushed, true);
            app.LasttaggedButton.Text = 'Last tagged';
            app.ASSIGNButton_60 = uibutton(app.IMAGELABELERUIFigure, 'push');
            app.ASSIGNButton_60.ButtonPushedFcn = createCallbackFcn(app, @ASSIGNButton_1Pushed, true);
            app.ASSIGNButton_60.Text = '60';
            app.LabelEditField_41Label = uilabel(app.IMAGELABELERUIFigure);
            app.LabelEditField_41Label.Text = 'Label:';
            app.LabelEditField_60 = uieditfield(app.IMAGELABELERUIFigure, 'text');
            app.ASSIGNButton_59 = uibutton(app.IMAGELABELERUIFigure, 'push');
            app.ASSIGNButton_59.ButtonPushedFcn = createCallbackFcn(app, @ASSIGNButton_1Pushed, true);
            app.ASSIGNButton_59.Text = '59';
            app.LabelEditField_42Label = uilabel(app.IMAGELABELERUIFigure);
            app.LabelEditField_42Label.Text = 'Label:';
            app.LabelEditField_59 = uieditfield(app.IMAGELABELERUIFigure, 'text');
            app.ASSIGNButton_58 = uibutton(app.IMAGELABELERUIFigure, 'push');
            app.ASSIGNButton_58.ButtonPushedFcn = createCallbackFcn(app, @ASSIGNButton_1Pushed, true);
            app.ASSIGNButton_58.Text = '58';
            app.LabelEditField_43Label = uilabel(app.IMAGELABELERUIFigure);
            app.LabelEditField_43Label.Text = 'Label:';
            app.LabelEditField_58 = uieditfield(app.IMAGELABELERUIFigure, 'text');
            app.ASSIGNButton_57 = uibutton(app.IMAGELABELERUIFigure, 'push');
            app.ASSIGNButton_57.ButtonPushedFcn = createCallbackFcn(app, @ASSIGNButton_1Pushed, true);
            app.ASSIGNButton_57.Text = '57';
            app.LabelEditField_44Label = uilabel(app.IMAGELABELERUIFigure);
            app.LabelEditField_44Label.Text = 'Label:';
            app.LabelEditField_57 = uieditfield(app.IMAGELABELERUIFigure, 'text');
            app.ASSIGNButton_56 = uibutton(app.IMAGELABELERUIFigure, 'push');
            app.ASSIGNButton_56.ButtonPushedFcn = createCallbackFcn(app, @ASSIGNButton_1Pushed, true);
            app.ASSIGNButton_56.Text = '56';
            app.LabelEditField_45Label = uilabel(app.IMAGELABELERUIFigure);
            app.LabelEditField_45Label.Text = 'Label:';
            app.LabelEditField_56 = uieditfield(app.IMAGELABELERUIFigure, 'text');
            app.ASSIGNButton_55 = uibutton(app.IMAGELABELERUIFigure, 'push');
            app.ASSIGNButton_55.ButtonPushedFcn = createCallbackFcn(app, @ASSIGNButton_1Pushed, true);
            app.ASSIGNButton_55.Text = '55';
            app.LabelEditField_46Label = uilabel(app.IMAGELABELERUIFigure);
            app.LabelEditField_46Label.Text = 'Label:';
            app.LabelEditField_55 = uieditfield(app.IMAGELABELERUIFigure, 'text');
            app.ASSIGNButton_54 = uibutton(app.IMAGELABELERUIFigure, 'push');
            app.ASSIGNButton_54.ButtonPushedFcn = createCallbackFcn(app, @ASSIGNButton_1Pushed, true);
            app.ASSIGNButton_54.Text = '54';
            app.LabelEditField_47Label = uilabel(app.IMAGELABELERUIFigure);
            app.LabelEditField_47Label.Text = 'Label:';
            app.LabelEditField_54 = uieditfield(app.IMAGELABELERUIFigure, 'text');
            app.ASSIGNButton_53 = uibutton(app.IMAGELABELERUIFigure, 'push');
            app.ASSIGNButton_53.ButtonPushedFcn = createCallbackFcn(app, @ASSIGNButton_1Pushed, true);
            app.ASSIGNButton_53.Text = '53';
            app.LabelEditField_48Label = uilabel(app.IMAGELABELERUIFigure);
            app.LabelEditField_48Label.Text = 'Label:';
            app.LabelEditField_53 = uieditfield(app.IMAGELABELERUIFigure, 'text');
            app.ASSIGNButton_52 = uibutton(app.IMAGELABELERUIFigure, 'push');
            app.ASSIGNButton_52.ButtonPushedFcn = createCallbackFcn(app, @ASSIGNButton_1Pushed, true);
            app.ASSIGNButton_52.Text = '52';
            app.LabelEditField_49Label = uilabel(app.IMAGELABELERUIFigure);
            app.LabelEditField_49Label.Text = 'Label:';
            app.LabelEditField_52 = uieditfield(app.IMAGELABELERUIFigure, 'text');
            app.ASSIGNButton_51 = uibutton(app.IMAGELABELERUIFigure, 'push');
            app.ASSIGNButton_51.ButtonPushedFcn = createCallbackFcn(app, @ASSIGNButton_1Pushed, true);
            app.ASSIGNButton_51.Text = '51';
            app.LabelEditField_50Label = uilabel(app.IMAGELABELERUIFigure);
            app.LabelEditField_50Label.Text = 'Label:';
            app.LabelEditField_51 = uieditfield(app.IMAGELABELERUIFigure, 'text');
            app.ASSIGNButton_50x = uibutton(app.IMAGELABELERUIFigure, 'push');
            app.ASSIGNButton_50x.ButtonPushedFcn = createCallbackFcn(app, @ASSIGNButton_1Pushed, true);
            app.ASSIGNButton_50x.Text = '50';
            app.LabelEditField_51Label = uilabel(app.IMAGELABELERUIFigure);
            app.LabelEditField_51Label.Text = 'Label:';
            app.LabelEditField_50 = uieditfield(app.IMAGELABELERUIFigure, 'text');
            app.ASSIGNButton_49 = uibutton(app.IMAGELABELERUIFigure, 'push');
            app.ASSIGNButton_49.ButtonPushedFcn = createCallbackFcn(app, @ASSIGNButton_1Pushed, true);
            app.ASSIGNButton_49.Text = '49';
            app.LabelEditField_52Label = uilabel(app.IMAGELABELERUIFigure);
            app.LabelEditField_52Label.Text = 'Label:';
            app.LabelEditField_49 = uieditfield(app.IMAGELABELERUIFigure, 'text');
            app.ASSIGNButton_48 = uibutton(app.IMAGELABELERUIFigure, 'push');
            app.ASSIGNButton_48.ButtonPushedFcn = createCallbackFcn(app, @ASSIGNButton_1Pushed, true);
            app.ASSIGNButton_48.Text = '48';
            app.LabelEditField_53Label = uilabel(app.IMAGELABELERUIFigure);
            app.LabelEditField_53Label.Text = 'Label:';
            app.LabelEditField_48 = uieditfield(app.IMAGELABELERUIFigure, 'text');
            app.ASSIGNButton_47 = uibutton(app.IMAGELABELERUIFigure, 'push');
            app.ASSIGNButton_47.ButtonPushedFcn = createCallbackFcn(app, @ASSIGNButton_1Pushed, true);
            app.ASSIGNButton_47.Text = '47';
            app.LabelEditField_54Label = uilabel(app.IMAGELABELERUIFigure);
            app.LabelEditField_54Label.Text = 'Label:';
            app.LabelEditField_47 = uieditfield(app.IMAGELABELERUIFigure, 'text');
            app.ASSIGNButton_46 = uibutton(app.IMAGELABELERUIFigure, 'push');
            app.ASSIGNButton_46.ButtonPushedFcn = createCallbackFcn(app, @ASSIGNButton_1Pushed, true);
            app.ASSIGNButton_46.Text = '46';
            app.LabelEditField_55Label = uilabel(app.IMAGELABELERUIFigure);
            app.LabelEditField_55Label.Text = 'Label:';
            app.LabelEditField_46 = uieditfield(app.IMAGELABELERUIFigure, 'text');
            app.ASSIGNButton_45 = uibutton(app.IMAGELABELERUIFigure, 'push');
            app.ASSIGNButton_45.ButtonPushedFcn = createCallbackFcn(app, @ASSIGNButton_1Pushed, true);
            app.ASSIGNButton_45.Text = '45';
            app.LabelEditField_56Label = uilabel(app.IMAGELABELERUIFigure);
            app.LabelEditField_56Label.Text = 'Label:';
            app.LabelEditField_45 = uieditfield(app.IMAGELABELERUIFigure, 'text');
            app.ASSIGNButton_44 = uibutton(app.IMAGELABELERUIFigure, 'push');
            app.ASSIGNButton_44.ButtonPushedFcn = createCallbackFcn(app, @ASSIGNButton_1Pushed, true);
            app.ASSIGNButton_44.Text = '44';
            app.LabelEditField_57Label = uilabel(app.IMAGELABELERUIFigure);
            app.LabelEditField_57Label.Text = 'Label:';
            app.LabelEditField_44 = uieditfield(app.IMAGELABELERUIFigure, 'text');
            app.ASSIGNButton_43 = uibutton(app.IMAGELABELERUIFigure, 'push');
            app.ASSIGNButton_43.ButtonPushedFcn = createCallbackFcn(app, @ASSIGNButton_1Pushed, true);
            app.ASSIGNButton_43.Text = '43';
            app.LabelEditField_58Label = uilabel(app.IMAGELABELERUIFigure);
            app.LabelEditField_58Label.Text = 'Label:';
            app.LabelEditField_43 = uieditfield(app.IMAGELABELERUIFigure, 'text');
            app.ASSIGNButton_42 = uibutton(app.IMAGELABELERUIFigure, 'push');
            app.ASSIGNButton_42.ButtonPushedFcn = createCallbackFcn(app, @ASSIGNButton_1Pushed, true);
            app.ASSIGNButton_42.Text = '42';
            app.LabelEditField_59Label = uilabel(app.IMAGELABELERUIFigure);
            app.LabelEditField_59Label.Text = 'Label:';
            app.LabelEditField_42 = uieditfield(app.IMAGELABELERUIFigure, 'text');
            app.ASSIGNButton_41 = uibutton(app.IMAGELABELERUIFigure, 'push');
            app.ASSIGNButton_41.ButtonPushedFcn = createCallbackFcn(app, @ASSIGNButton_1Pushed, true);
            app.ASSIGNButton_41.Text = '41';
            app.LabelEditField_60Label = uilabel(app.IMAGELABELERUIFigure);
            app.LabelEditField_60Label.Text = 'Label:';
            app.LabelEditField_41 = uieditfield(app.IMAGELABELERUIFigure, 'text');
        end
    end

    methods (Access = public)

        % Construct app
        function app = ImageLabeler_box_v2

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.IMAGELABELERUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.IMAGELABELERUIFigure)
        end
    end
end